classdef BlackJack_Game_Skeleton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Button_4              matlab.ui.control.Button
        Image5_2              matlab.ui.control.Image
        Image6                matlab.ui.control.Image
        Image5                matlab.ui.control.Image
        Button_3              matlab.ui.control.StateButton
        WinCapEditField       matlab.ui.control.NumericEditField
        WinCapEditFieldLabel  matlab.ui.control.Label
        Image2                matlab.ui.control.Image
        CompEditField         matlab.ui.control.NumericEditField
        CompEditFieldLabel    matlab.ui.control.Label
        P2EditField           matlab.ui.control.NumericEditField
        P2EditFieldLabel      matlab.ui.control.Label
        P1EditField           matlab.ui.control.NumericEditField
        P1EditFieldLabel      matlab.ui.control.Label
        RollsLabel_3          matlab.ui.control.Label
        P1RollsLabel          matlab.ui.control.Label
        UITable_2             matlab.ui.control.Table
        UITable               matlab.ui.control.Table
        Button_2              matlab.ui.control.Button
        PointsLabel           matlab.ui.control.Label
        Image4                matlab.ui.control.Image
    end

    
    properties (Access = private)
        playerCount;
        isRollClicked = false;
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winCapValue, PlayersValue)

            app.WinCapEditField.Value = winCapValue;
            app.playerCount = PlayersValue;

            maxPlayers = round(str2double(PlayersValue));

            PlayersName = cell(1, maxPlayers);
            PlayersName{1} = 'P1';

            players(1, maxPlayers) = Player();
            for i = 1:maxPlayers
                players(i) = Player(PlayersName{i});
            end

            DiceDealer = Dice();
            DiceDealer.Score = 0;
            DiceDealer.Name = "House";

            % --- First roll for players ---

            num = length(players);  % number of players
            for x = 1:2
                for i = 1:num
                    app.Button_4.Visible = "on";
                    app.isRollClicked = false;

                    while ~app.isRollClicked
                        pause(0.5);
                        drawnow;
                    end

                    app.Button_4.Visible = "off";

                    roll = randi([2,14]);  % roll dice from 1 to 11
                    if ismember(roll, [10,12,13,14])
                        roll = 10;
                    end

                    fprintf('%s rolled a %d!\n', players(i).Name, roll);


                    if roll == 11
                        isValid = false;
                        fprintf('%s, choose if you want your roll to be 1 or 11.\n', players(i).Name)
                        while isValid == false
                            roll = inputdlg('Enter your choice (1 or 11):', 'Ace Choice', [1, 45]);
                            roll = round(str2double(roll{1}));
                            if ismember(roll, [1, 11])
                                fprintf('Your roll is %d\n', roll)
                                isValid = true;
                            else
                                fprintf('Please enter a valid number (1 or 11) ')
                            end
                        end
                    end

                    players(i).UpdateScore(roll);
                    app.P1EditField.Value = players(i).Score;
                end

                DiceDealer.Roll();
                fprintf('House rolled a %d!\n', DiceDealer.Score);
                app.CompEditField.Value = DiceDealer.Score;
            end
        end

        % Button pushed function: Button_2
        function Exit(app, event)
            delete(app)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.isRollClicked = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Theater Background.png');
            app.PointsLabel = uilabel(app.UIFigure);
            app.PointsLabel.Text = 'Points';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Exit, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'Exit.png');
            app.Button_2.Text = '';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'1st'; '2nd'; '3rd'; '4th'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'1st'; '2nd'; '3rd'; '4th'};
            app.UITable_2.RowName = {};
            app.P1RollsLabel = uilabel(app.UIFigure);
            app.P1RollsLabel.Text = 'P1 Rolls';
            app.RollsLabel_3 = uilabel(app.UIFigure);
            app.RollsLabel_3.Text = 'Rolls';
            app.P1EditFieldLabel = uilabel(app.UIFigure);
            app.P1EditFieldLabel.Text = 'P1:';
            app.P1EditField = uieditfield(app.UIFigure, 'numeric');
            app.P1EditField.Editable = 'off';
            app.P2EditFieldLabel = uilabel(app.UIFigure);
            app.P2EditFieldLabel.Text = 'P2:';
            app.P2EditField = uieditfield(app.UIFigure, 'numeric');
            app.P2EditField.Editable = 'off';
            app.CompEditFieldLabel = uilabel(app.UIFigure);
            app.CompEditFieldLabel.Text = 'Comp:';
            app.CompEditField = uieditfield(app.UIFigure, 'numeric');
            app.CompEditField.Editable = 'off';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Computer.png');
            app.WinCapEditFieldLabel = uilabel(app.UIFigure);
            app.WinCapEditFieldLabel.Text = 'Win Cap';
            app.WinCapEditField = uieditfield(app.UIFigure, 'numeric');
            app.WinCapEditField.Editable = 'off';
            app.Button_3 = uibutton(app.UIFigure, 'state');
            app.Button_3.Visible = 'off';
            app.Button_3.Icon = fullfile(pathToMLAPP, 'Hold.png');
            app.Button_3.Text = '';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'Dice-11.png');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.Visible = 'off';
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'Dice-11.png');
            app.Image5_2 = uiimage(app.UIFigure);
            app.Image5_2.Visible = 'off';
            app.Image5_2.ImageSource = fullfile(pathToMLAPP, 'Dice-11.png');
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'Roll.png');
            app.Button_4.Visible = 'off';
            app.Button_4.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BlackJack_Game_Skeleton(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end